classdef stimulusV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        Panel_7                        matlab.ui.container.Panel
        GridLayout12                   matlab.ui.container.GridLayout
        EastAttenuation                matlab.ui.control.Spinner
        WestAttenuation                matlab.ui.control.Spinner
        EastattndBLabel                matlab.ui.control.Label
        WestattndBLabel                matlab.ui.control.Label
        WestAttenuationSlider          matlab.ui.control.Slider
        EastAttenuationSlider          matlab.ui.control.Slider
        EastStimulusPlot               matlab.ui.control.UIAxes
        EastModulatorPlot              matlab.ui.control.UIAxes
        EastCarrierPlot                matlab.ui.control.UIAxes
        WestStimulusPlot               matlab.ui.control.UIAxes
        WestModulatorPlot              matlab.ui.control.UIAxes
        WestCarrierPlot                matlab.ui.control.UIAxes
        ParadigmTabGroup               matlab.ui.container.TabGroup
        SearchTab                      matlab.ui.container.Tab
        SearchTabLayout                matlab.ui.container.GridLayout
        SearchSourceParametersSwitch   matlab.ui.control.Switch
        SearchMode                     matlab.ui.control.DiscreteKnob
        SearchSourceParametersTab      matlab.ui.container.TabGroup
        WestTab                        matlab.ui.container.Tab
        GridLayout29                   matlab.ui.container.GridLayout
        WestSearchModulatorPanel       matlab.ui.container.Panel
        GridLayout22                   matlab.ui.container.GridLayout
        GridLayout14_28                matlab.ui.container.GridLayout
        Spinner_76                     matlab.ui.control.Spinner
        Spinner_75                     matlab.ui.control.Spinner
        CheckBox_24                    matlab.ui.control.CheckBox
        GridLayout14_27                matlab.ui.container.GridLayout
        Spinner_74                     matlab.ui.control.Spinner
        Spinner_73                     matlab.ui.control.Spinner
        CheckBox_23                    matlab.ui.control.CheckBox
        GridLayout14_26                matlab.ui.container.GridLayout
        Spinner_72                     matlab.ui.control.Spinner
        Spinner_71                     matlab.ui.control.Spinner
        CheckBox_22                    matlab.ui.control.CheckBox
        GridLayout14_25                matlab.ui.container.GridLayout
        Spinner_70                     matlab.ui.control.Spinner
        Spinner_69                     matlab.ui.control.Spinner
        CheckBox_21                    matlab.ui.control.CheckBox
        GridLayout14_24                matlab.ui.container.GridLayout
        Spinner_68                     matlab.ui.control.Spinner
        Spinner_67                     matlab.ui.control.Spinner
        CheckBox_20                    matlab.ui.control.CheckBox
        GridLayout14_23                matlab.ui.container.GridLayout
        Spinner_66                     matlab.ui.control.Spinner
        Spinner_65                     matlab.ui.control.Spinner
        CheckBox_19                    matlab.ui.control.CheckBox
        GridLayout14_22                matlab.ui.container.GridLayout
        EnergyLabel_6                  matlab.ui.control.Label
        FrequencyHzLabel_6             matlab.ui.control.Label
        WestSearchCarrierPanel         matlab.ui.container.Panel
        SearchCarrierLayout            matlab.ui.container.GridLayout
        GridLayout14_21                matlab.ui.container.GridLayout
        Spinner_64                     matlab.ui.control.Spinner
        Spinner_63                     matlab.ui.control.Spinner
        CheckBox_18                    matlab.ui.control.CheckBox
        GridLayout14_20                matlab.ui.container.GridLayout
        Spinner_62                     matlab.ui.control.Spinner
        Spinner_61                     matlab.ui.control.Spinner
        CheckBox_17                    matlab.ui.control.CheckBox
        GridLayout14_19                matlab.ui.container.GridLayout
        Spinner_60                     matlab.ui.control.Spinner
        Spinner_59                     matlab.ui.control.Spinner
        CheckBox_16                    matlab.ui.control.CheckBox
        GridLayout14_18                matlab.ui.container.GridLayout
        Spinner_58                     matlab.ui.control.Spinner
        Spinner_57                     matlab.ui.control.Spinner
        CheckBox_15                    matlab.ui.control.CheckBox
        GridLayout14_17                matlab.ui.container.GridLayout
        Spinner_56                     matlab.ui.control.Spinner
        Spinner_55                     matlab.ui.control.Spinner
        CheckBox_14                    matlab.ui.control.CheckBox
        GridLayout14_16                matlab.ui.container.GridLayout
        Spinner_54                     matlab.ui.control.Spinner
        Spinner_53                     matlab.ui.control.Spinner
        CheckBox_13                    matlab.ui.control.CheckBox
        GridLayout14_15                matlab.ui.container.GridLayout
        EnergyLabel_5                  matlab.ui.control.Label
        FrequencyHzLabel_5             matlab.ui.control.Label
        EastTab                        matlab.ui.container.Tab
        GridLayout30                   matlab.ui.container.GridLayout
        SearchModulatorPanel_2         matlab.ui.container.Panel
        GridLayout22_2                 matlab.ui.container.GridLayout
        GridLayout14_42                matlab.ui.container.GridLayout
        Spinner_100                    matlab.ui.control.Spinner
        Spinner_99                     matlab.ui.control.Spinner
        CheckBox_36                    matlab.ui.control.CheckBox
        GridLayout14_41                matlab.ui.container.GridLayout
        Spinner_98                     matlab.ui.control.Spinner
        Spinner_97                     matlab.ui.control.Spinner
        CheckBox_35                    matlab.ui.control.CheckBox
        GridLayout14_40                matlab.ui.container.GridLayout
        Spinner_96                     matlab.ui.control.Spinner
        Spinner_95                     matlab.ui.control.Spinner
        CheckBox_34                    matlab.ui.control.CheckBox
        GridLayout14_39                matlab.ui.container.GridLayout
        Spinner_94                     matlab.ui.control.Spinner
        Spinner_93                     matlab.ui.control.Spinner
        CheckBox_33                    matlab.ui.control.CheckBox
        GridLayout14_38                matlab.ui.container.GridLayout
        Spinner_92                     matlab.ui.control.Spinner
        Spinner_91                     matlab.ui.control.Spinner
        CheckBox_32                    matlab.ui.control.CheckBox
        GridLayout14_37                matlab.ui.container.GridLayout
        Spinner_90                     matlab.ui.control.Spinner
        Spinner_89                     matlab.ui.control.Spinner
        CheckBox_31                    matlab.ui.control.CheckBox
        GridLayout14_36                matlab.ui.container.GridLayout
        EnergyLabel_8                  matlab.ui.control.Label
        FrequencyHzLabel_8             matlab.ui.control.Label
        EastSearchCarrierPanel         matlab.ui.container.Panel
        SearchCarrierLayout_2          matlab.ui.container.GridLayout
        GridLayout14_35                matlab.ui.container.GridLayout
        Spinner_88                     matlab.ui.control.Spinner
        Spinner_87                     matlab.ui.control.Spinner
        CheckBox_30                    matlab.ui.control.CheckBox
        GridLayout14_34                matlab.ui.container.GridLayout
        Spinner_86                     matlab.ui.control.Spinner
        Spinner_85                     matlab.ui.control.Spinner
        CheckBox_29                    matlab.ui.control.CheckBox
        GridLayout14_33                matlab.ui.container.GridLayout
        Spinner_84                     matlab.ui.control.Spinner
        Spinner_83                     matlab.ui.control.Spinner
        CheckBox_28                    matlab.ui.control.CheckBox
        GridLayout14_32                matlab.ui.container.GridLayout
        Spinner_82                     matlab.ui.control.Spinner
        Spinner_81                     matlab.ui.control.Spinner
        CheckBox_27                    matlab.ui.control.CheckBox
        GridLayout14_31                matlab.ui.container.GridLayout
        Spinner_80                     matlab.ui.control.Spinner
        Spinner_79                     matlab.ui.control.Spinner
        CheckBox_26                    matlab.ui.control.CheckBox
        GridLayout14_30                matlab.ui.container.GridLayout
        Spinner_78                     matlab.ui.control.Spinner
        Spinner_77                     matlab.ui.control.Spinner
        CheckBox_25                    matlab.ui.control.CheckBox
        GridLayout14_29                matlab.ui.container.GridLayout
        EnergyLabel_7                  matlab.ui.control.Label
        FrequencyHzLabel_7             matlab.ui.control.Label
        SearchCyclerPanel              matlab.ui.container.Panel
        GridLayout11                   matlab.ui.container.GridLayout
        EndLabel                       matlab.ui.control.Label
        StepLabel                      matlab.ui.control.Label
        StartLabel                     matlab.ui.control.Label
        SearchEnd                      matlab.ui.control.Spinner
        SearchStep                     matlab.ui.control.Spinner
        SearchStart                    matlab.ui.control.Spinner
        Tab2                           matlab.ui.container.Tab
        SystemPanel                    matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        Panel_3                        matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        Species                        matlab.ui.control.DropDown
        SpeciesDropDownLabel           matlab.ui.control.Label
        TDTStatusPanel                 matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        LoadParamsLamp                 matlab.ui.control.Lamp
        LoadParamsLampLabel            matlab.ui.control.Label
        LoadCircuitLamp                matlab.ui.control.Lamp
        LoadCircuitLampLabel           matlab.ui.control.Label
        ConnectionLamp                 matlab.ui.control.Lamp
        ConnectionLampLabel            matlab.ui.control.Label
        MasterSwitch                   matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        Panel_2                        matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        InterStimulusIntervalSlider    matlab.ui.control.Slider
        StimulusDuration               matlab.ui.control.NumericEditField
        StimulusDurationmsecLabel      matlab.ui.control.Label
        InterStimulusIntervalsecLabel  matlab.ui.control.Label
        InterStimulusIntervalEditBox   matlab.ui.control.NumericEditField
        Panel_5                        matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        InterSpeakerIntervalSlider     matlab.ui.control.Slider
        Source                         matlab.ui.control.DiscreteKnob
        InterSpeakerIntervalmsecLabel  matlab.ui.control.Label
        InterSpeakerIntervalEditBox    matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        MasterState % Description.
        fs = 24144.0625; % Sampling Rate of TDT System III (Hz).
        BaseAttenuation = -27; % Default Attenuation Setting on the TDT PA (dB).
        RPcontroller;
        noDSP;
    end

    methods (Access = private)
        %% Hardware Interaction Methods:
        function hardware_initialization(app)
            app.RPcontroller = actxserver('RPco.x');
            if app.RPcontroller.ConnectRP2('GB', 1)
                app.ConnectionLamp.Color = [0, 1, 0];
            else
                app.ConnectionLamp.Color = [1, 0, 0];
            end
        end

        function e = load_circuit(app, circuit)
            e = app.RPcontroller.LoadCOF(circuit);
            if e
                app.LoadCircuitLamp.Color = [0, 1, 0];
            else
                app.LoadCircuitLamp.Color = [1, 0, 0];
            end
        end
        function e = transfer_parameters(app, west, east)
            e = zeros(8, 1);
            e(1) = app.RPcontroller.WriteTagV('EastPulseTrigger', 0, east.pulseTriggers);
            e(2) = app.RPcontroller.WriteTagV('EastStimulusTrigger', 0, east.stimulusTriggers);
            e(3) = app.RPcontroller.WriteTagV('EastStimulus', 0, east.stimulus);
            e(4) = app.RPcontroller.SetTagVal('EastStimulusPoints', size(east.stimulus, 2));
            e(5) = app.RPcontroller.WriteTagV('WestPulseTrigger', 0, west.pulseTriggers);
            e(6) = app.RPcontroller.WriteTagV('WestStimulusTrigger', 0, west.stimulusTriggers);
            e(7) = app.RPcontroller.WriteTagV('WestStimulus', 0, west.stimulus);
            e(8) = app.RPcontroller.SetTagVal('WestStimulusPoints', size(west.stimulus, 2));
            e = prod(e, 'all');
            if e
                app.LoadParamsLamp.Color = [0, 1, 0];
            else
                app.LoadParamsLamp.Color = [1, 0, 0];
            end
        end

        %% Helper Methods:
        function y = attenuateRelativeToBase(app, x, dB)
            y = x.*10.^((dB-app.BaseAttenuation)./20);
        end

        function y = attenuate(~, x, dB)
            y = x.*10.^(dB./20);
        end

        function y = appendSamplesAtStart(~, x, samples)
            y = cat(2, zeros(size(x, 1), samples), x);
        end

        function y = appendSamplesAtEnd(~, x, samples)
            y = cat(2, x, zeros(size(x, 1), samples));
        end

        function speaker = padAtStart(app, speaker, samples)
            speaker.carrier = app.appendSamplesAtStart(speaker.carrier, samples);
            speaker.modulator = app.appendSamplesAtStart(speaker.modulator, samples);
            speaker.stimulus = app.appendSamplesAtStart(speaker.stimulus, samples);
            speaker.stimulusTriggers = app.appendSamplesAtStart(speaker.stimulusTriggers, samples);
            speaker.pulseTriggers = app.appendSamplesAtStart(speaker.pulseTriggers, samples);
        end

        function speaker = padAtEnd(app, speaker, samples)
            speaker.carrier = app.appendSamplesAtEnd(speaker.carrier, samples);
            speaker.modulator = app.appendSamplesAtEnd(speaker.modulator, samples);
            speaker.stimulus = app.appendSamplesAtEnd(speaker.stimulus, samples);
            speaker.stimulusTriggers = app.appendSamplesAtEnd(speaker.stimulusTriggers, samples);
            speaker.pulseTriggers = app.appendSamplesAtEnd(speaker.pulseTriggers, samples);
        end

        %% Search Stimulus Methods:
        function y = getSearchParameters(app, kind)
            y = struct;
            if strcmp(app.SearchSourceParametersSwitch.Value, "Unified")
                frequency_editboxes = app.SearchSourceParametersTab.SelectedTab.findobj('Title', kind).findobj('Tag', 'frequency').findobj('Enable', 1);
                energy_editboxes = app.SearchSourceParametersTab.SelectedTab.findobj('Title', 'Energy').findobj('Tag', 'energy').findobj('Enable', 1);
                y(1).speaker = app.SearchSourceParametersTab.SelectedTab.Title;
                y(1).frequency = zeros(size(frequency_editboxes));
                y(1).energy = zeros(size(energy_editboxes));
                for i = 1: 1: size(frequency_editboxes, 1)
                    y(1).frequency(i) = frequency_editboxes(i).Value;
                    y(1).energy(i) = energy_editboxes(i).Value;
                end
                y(1).nItems = size(y(1).frequency, 2);
            else
                sources = app.SearchSourceParametersTab.findobj('Tag', 'SourceTab');
                y = struct;
                for i = 1: 1: size(sources, 1)
                    frequency_editboxes = sources.findobj('Title', kind).findobj('Tag', 'frequency').findobj('Enable', 1);
                    energy_editboxes = sources.findobj('Title', 'Energy').findobj('Tag', 'energy').findobj('Enable', 1);
                    y(1).speaker = sources(i).Title;
                    for j = 1: 1: size(frequency_editboxes, 1)
                        y(i).frequency(j) = frequency_editboxes(j).Value;
                        y(i).energy(j) = energy_editboxes(j).Value;
                    end
                    y(i).nItems = size(y(i).frequency, 2);
                end
            end
        end

        function [carriers, modulators, stimuli] = buildSearchStimulus(app)
            stimuli = struct;
            carriers = app.getSearchParameters('Carrier');
            modulators = app.getSearchParameters('Modulator');
            times = 0: (1/app.fs): (app.StimulusDuration.Value*1e-3) - (1/app.fs);
            for i = 1: 1: size(carriers)
                carriers(i).waveform = sin(2*pi.*carriers.frequency*times);
                if app.SearchMode.Value == "Fixed Carrier"
                    carriers(i).waveform = carriers(1).energy'*carriers(i).waveform;
                end
                modulators(i).waveform = 0.5*sin(2*pi.*modulators(i).frequency*times - pi/2)+0.5;
                if app.SearchMode.Value == "Fixed Modulation"
                    modulators(i).waveform = modulators(1).energy'*modulators(i).waveform;
                end
                carriers(i).waveform = reshape(carriers(i).waveform, [size(carriers(i).waveform, 1), 1, size(carriers(i).waveform, 2)]);
                modulators(i).waveform = reshape(modulators(i).waveform, [1, size(modulators(i).waveform, 1), size(modulators(i).waveform, 2)]);
                stimuli(i).waveform = 10.*pagemtimes(carriers(i).waveform, modulators(i).waveform);
                stimuli(i).triggers = zeros(size(stimuli(i).waveform));
                stimuli(i).triggers(:, :, 1) = 1;
            end
        end

        function search_stimulus_out(app)
            [carriers, modulators, stimuli] = app.buildSearchStimulus();
            maxCarrierItems = 0;
            maxModulatorItems = 0;
            for s = 1: 1: size(stimuli)
                if maxCarrierItems < carriers(s).nItems
                    maxCarrierItems = carriers(s).nItems;
                end
                if maxModulatorItems < modulators(s).nItems
                    maxModulatorItems = modulators(s).nItems;
                end
            end
            carrier = struct;
            modulator = struct;
            stimulus = struct;
            while 1
                for i = 1: 1: maxCarrierItems
                    for j = 1: 1: maxModulatorItems
                        for s = 1: 1: size(stimuli)
                            carrier(s).frequency = carriers(s).frequency(min(i, carriers(s).nItems));
                            carrier(s).waveform = squeeze(carriers(s).waveform(min(i, carriers(s).nItems), 1, :)');
                            modulator(s).frequency = modulator(s).frequency(min(j, modulators(s).nItems));
                            modulator(s).waveform = squeeze(modulators(s).waveform(1, min(j, moudlators(s).nItems), :)');
                            stimulus(s).waveform = squeeze(stimuli(s).waveform(min(i, carriers(s).nItems), min(j, moudlators(s).nItems), :)');
                            stimulus(s).stimTrigs = zeros(size(stimulus(s).waveform));
                            stimulus(s).stimTrigs(:, :, 1) = 1;
                            stimulus(s).pulseTrigs = stimulus(s).stimTrigs;
                            
                        end
                    end
                end
                
                for i = 1: 1: size(stimuli.waveform, 1)
                    for j = 1: 1: size(stimuli.waveform, 2)
                        tstart = tic();
                        carrier = squeeze(carriers.waveform(i, 1, :))';
                        modulator = squeeze(modulators.waveform(1, j, :))';
                        stimulus = squeeze(stimuli.waveform(i, j, :))';
                        triggers = squeeze(stimuli.triggers(i, j, :))';
                        west.carrier = carrier;
                        west.modulator = modulator;
                        west.stimulus = stimulus;
                        west.stimulusTriggers = triggers;
                        west.pulseTriggers = triggers;
                        east.carrier = carrier;
                        east.modulator = modulator;
                        east.stimulus = stimulus;
                        east.stimulusTriggers = triggers;
                        east.pulseTriggers = triggers;
                        if strcmp(app.Source.Value, "West")
                            east.carrier = east.carrier.*0;
                            east.modulator = east.modulator.*0;
                            east.stimulus = east.stimulus.*0;
                            east.stimulusTriggers = east.stimulusTriggers.*0;
                            east.pulseTriggers = east.pulseTriggers.*0;
                        elseif strcmp(app.Source.Value, "East")
                            west.carrier = west.carrier.*0;
                            west.modulator = west.modulator.*0;
                            west.stimulus = west.stimulus.*0;
                            west.stimulusTriggers = west.stimulusTriggers.*0;
                            west.pulseTriggers = west.pulseTriggers.*0;
                        elseif strcmp(app.Source.Value, "Biaural")
                            append_samples = abs(floor(app.InterSpeakerIntervalEditBox.Value*1e-3*app.fs));
                            if app.InterSpeakerIntervalEditBox.Value > 0
                                west = app.padAtEnd(west, append_samples);
                                east = app.padAtStart(east, append_samples);
                            else
                                east = app.padAtEnd(east, append_samples);
                                west = app.padAtStart(west, append_samples);
                            end
                        end
                        if app.noDSP == false
                            app.RPcontroller.ClearCOF();
                            if app.load_circuit("biaural_circuit.rcx") == 0
                                error("Cannot load circuit.");
                            end
                        end

                        times = linspace(0, (app.StimulusDuration.Value+app.InterSpeakerIntervalEditBox.Value)*1e-3, size(east.stimulus, 2));
                        west.stimulus = app.attenuateRelativeToBase(west.stimulus, app.WestAttenuation.Value);
                        east.stimulus = app.attenuateRelativeToBase(east.stimulus, app.EastAttenuation.Value);
                        if app.noDSP == false
                            if app.transfer_parameters(west, east) == 0
                                error("Cannot load buffers and parameters.");
                            end
                            app.RPcontroller.Run();
                            app.RPcontroller.SoftTrg(1);
                            app.RPcontroller.SoftTrg(2);
                        end
                        plot(app.WestCarrierPlot, west.carrier, 'LineWidth', 0.5, "XData", times);
                        plot(app.WestModulatorPlot, west.modulator, 'LineWidth', 0.5, "XData", times);
                        plot(app.WestStimulusPlot, app.attenuate(west.stimulus, app.BaseAttenuation), 'LineWidth', 0.5, "XData", times);
                        plot(app.EastCarrierPlot, east.carrier, 'LineWidth', 0.5, "XData", times);
                        plot(app.EastModulatorPlot, east.modulator, 'LineWidth', 0.5, "XData", times);
                        plot(app.EastStimulusPlot, app.attenuate(east.stimulus, app.BaseAttenuation), 'LineWidth', 0.5, "XData", times);
                        if app.MasterState == 1
                            return;
                        end
                        pause(app.InterStimulusIntervalEditBox.Value - toc(tstart));
                    end
                end
            end
        end

        %% App methods:
        function sound_out(app)
            app.MasterState = 0;
            if app.ParadigmTabGroup.SelectedTab == app.SearchTab
                app.search_stimulus_out();
            elseif app.ParadigmTabGroup.SelectedTab == app.PRRTab
                app.prr_stimulus_out();
            elseif app.ParadigmTabGroup.SelectedTab == app.NaturalTab
                app.natural_stimulus_out();
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, noDSP)
            arguments
                app;
                noDSP(1, 1) logical {mustBeNumericOrLogical} = false;
            end
            app.noDSP = noDSP;
            app.InterSpeakerIntervalEditBox.Limits = app.InterSpeakerIntervalSlider.Limits;
            app.InterStimulusIntervalEditBox.Limits = app.InterStimulusIntervalSlider.Limits;
            if noDSP == false
                app.hardware_initialization();
            end
        end

        % Value changed function: InterStimulusIntervalSlider
        function InterStimulusIntervalSliderValueChanged(app, event)
            app.InterStimulusIntervalEditBox.Value = app.InterStimulusIntervalSlider.Value;
        end

        % Callback function
        function InterStimulusIntervalEditBoxValueChanged2(app, event)
            app.InterStimulusIntervalSlider.Value = app.InterStimulusIntervalEditBox.Value;
        end

        % Value changed function: InterSpeakerIntervalSlider
        function InterSpeakerIntervalSliderValueChanged(app, event)
            app.InterSpeakerIntervalEditBox.Value = app.InterSpeakerIntervalSlider.Value;
        end

        % Callback function
        function InterSpeakerIntervalEditBoxValueChanged2(app, event)
            app.InterSpeakerIntervalSlider.Value = app.InterSpeakerIntervalEditBox.Value;
        end

        % Button pushed function: MasterSwitch
        function MasterSwitchPushed(app, event)
            if app.MasterSwitch.BackgroundColor(2) == 1.00
                app.MasterSwitch.Icon = "./icons/mute.svg";
                app.MasterSwitch.BackgroundColor = [1.00,0.00,0.00];
                app.sound_out();
            else
                app.MasterSwitch.Icon = "./icons/unmute.svg";
                app.MasterSwitch.BackgroundColor = [0.00,1.00,0.00];
                app.MasterState = 1;
            end
        end

        % Callback function
        function CheckBoxValueChanged(app, event)
            event.Source.Parent.findobj('Tag', 'frequency').Enable = event.Source.Value;
            event.Source.Parent.findobj('Tag', 'energy').Enable = event.Source.Value;
        end

        % Value changed function: SearchMode
        function SearchModeValueChanged(app, event)
            value = app.SearchMode.Value;
            e = {};
            if value == "Normal"
                checkboxes = app.WestSearchCarrierPanel.findobj('Tag', 'check');
                checkboxes(1).Value = 0;
                e.Source = checkboxes(1);
                app.CheckBoxValueChanged(e);
                for i = 2: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 1;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                checkboxes = app.WestSearchModulatorPanel.findobj('Tag', 'check');
                checkboxes(1).Value = 0;
                e.Source = checkboxes(1);
                app.CheckBoxValueChanged(e);
                checkboxes(2).Value = 0;
                e.Source = checkboxes(2);
                app.CheckBoxValueChanged(e);
                for i = 3: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 1;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                editboxes = app.SearchCyclerPanel.findobj('Tag', 'frequency');
                for i = 1: 1: size(editboxes, 1)
                    editboxes(i).Enable = 0;
                end
            elseif value == "Fixed Modulation"
                checkboxes = app.WestSearchCarrierPanel.findobj('Tag', 'check');
                for i = 1: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 0;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                checkboxes = app.WestSearchModulatorPanel.findobj('Tag', 'check');
                checkboxes(1).Value = 1;
                e.Source = checkboxes(1);
                app.CheckBoxValueChanged(e);
                for i = 2: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 0;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                editboxes = app.SearchCyclerPanel.findobj('Tag', 'frequency');
                for i = 1: 1: size(editboxes, 1)
                    editboxes(i).Enable = 1;
                end
            elseif value == "Fixed Carrier"
                checkboxes = app.WestSearchModulatorPanel.findobj('Tag', 'check');
                for i = 1: 1: size(checkboxes, 1)
                    checkboxes(i).Value = 0;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                checkboxes = app.SearchCarrierLayout.findobj('Tag', 'check');
                checkboxes(1).Value = 1;
                e.Source = checkboxes(1);
                app.CheckBoxValueChanged(e);
                for i = 2: 1: size(checkboxes)
                    checkboxes(i).Value = 0;
                    e.Source = checkboxes(i);
                    app.CheckBoxValueChanged(e);
                end
                editboxes = app.SearchCyclerPanel.findobj('Tag', 'frequency');
                for i = 1: 1: size(editboxes, 1)
                    editboxes(i).Enable = 1;
                end
            end
        end

        % Value changed function: WestAttenuationSlider
        function WestAttenuationSliderValueChanged(app, event)
            app.WestAttenuation.Value = app.WestAttenuationSlider.Value;
        end

        % Value changed function: EastAttenuationSlider
        function EastAttenuationSliderValueChanged(app, event)
            app.EastAttenuation.Value = app.EastAttenuationSlider.Value;
        end

        % Value changed function: EastAttenuation
        function EastAttenuationValueChanged(app, event)
            app.EastAttenuationSlider.Value = app.EastAttenuation.Value;
        end

        % Value changed function: WestAttenuation
        function WestAttenuationValueChanged(app, event)
            app.WestAttenuationSlider.Value = app.WestAttenuation.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SystemPanel = uipanel(app.GridLayout);
            app.SystemPanel.Title = 'System';
            app.GridLayout2 = uigridlayout(app.SystemPanel);
            app.Panel_5 = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel_5);
            app.InterSpeakerIntervalEditBox = uieditfield(app.GridLayout4, 'numeric');
            app.InterSpeakerIntervalEditBox.ValueChangedFcn = createCallbackFcn(app, @InterSpeakerIntervalEditBoxValueChanged, true);
            app.InterSpeakerIntervalEditBox.Value = 5;
            app.InterSpeakerIntervalmsecLabel = uilabel(app.GridLayout4);
            app.InterSpeakerIntervalmsecLabel.Text = 'Inter-Speaker Interval (msec)';
            app.Source = uiknob(app.GridLayout4, 'discrete');
            app.Source.Items = {'West', 'Biaural', 'East'};
            app.Source.Value = 'West';
            app.Panel = uipanel(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.InterSpeakerIntervalSlider = uislider(app.GridLayout5);
            app.InterSpeakerIntervalSlider.Limits = [-40 40];
            app.InterSpeakerIntervalSlider.MajorTicks = [-40 -35 -30 -25 -20 -15 -10 -5 0 5 10 15 20 25 30 35 40];
            app.InterSpeakerIntervalSlider.ValueChangedFcn = createCallbackFcn(app, @InterSpeakerIntervalSliderValueChanged, true);
            app.InterSpeakerIntervalSlider.MinorTicks = [-40 -39 -38 -37 -36 -35 -34 -33 -32 -31 -30 -29 -28 -27 -26 -25 -24 -23 -22 -21 -20 -19 -18 -17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.InterSpeakerIntervalSlider.Value = 5;
            app.Panel_4 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_4);
            app.InterStimulusIntervalEditBox = uieditfield(app.GridLayout3, 'numeric');
            app.InterStimulusIntervalEditBox.ValueChangedFcn = createCallbackFcn(app, @InterStimulusIntervalEditBoxValueChanged, true);
            app.InterStimulusIntervalEditBox.Value = 1;
            app.InterStimulusIntervalsecLabel = uilabel(app.GridLayout3);
            app.InterStimulusIntervalsecLabel.Text = 'Inter-Stimulus Interval (sec)';
            app.StimulusDurationmsecLabel = uilabel(app.GridLayout3);
            app.StimulusDurationmsecLabel.Text = 'Stimulus Duration (msec)';
            app.StimulusDuration = uieditfield(app.GridLayout3, 'numeric');
            app.StimulusDuration.Value = 400;
            app.Panel_2 = uipanel(app.GridLayout3);
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.InterStimulusIntervalSlider = uislider(app.GridLayout6);
            app.InterStimulusIntervalSlider.Limits = [0.5 6.5];
            app.InterStimulusIntervalSlider.MajorTicks = [0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5];
            app.InterStimulusIntervalSlider.ValueChangedFcn = createCallbackFcn(app, @InterStimulusIntervalSliderValueChanged, true);
            app.InterStimulusIntervalSlider.MinorTicks = [];
            app.InterStimulusIntervalSlider.Value = 1;
            app.MasterSwitch = uibutton(app.GridLayout2, 'push');
            app.MasterSwitch.ButtonPushedFcn = createCallbackFcn(app, @MasterSwitchPushed, true);
            app.MasterSwitch.Icon = fullfile(pathToMLAPP, '.', 'icons', 'unmute.svg');
            app.MasterSwitch.Text = '';
            app.TDTStatusPanel = uipanel(app.GridLayout2);
            app.TDTStatusPanel.Title = 'TDT Status';
            app.GridLayout7 = uigridlayout(app.TDTStatusPanel);
            app.ConnectionLampLabel = uilabel(app.GridLayout7);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.GridLayout7);
            app.LoadCircuitLampLabel = uilabel(app.GridLayout7);
            app.LoadCircuitLampLabel.Text = 'Load Circuit';
            app.LoadCircuitLamp = uilamp(app.GridLayout7);
            app.LoadParamsLampLabel = uilabel(app.GridLayout7);
            app.LoadParamsLampLabel.Text = 'Load Params';
            app.LoadParamsLamp = uilamp(app.GridLayout7);
            app.Panel_3 = uipanel(app.GridLayout2);
            app.GridLayout8 = uigridlayout(app.Panel_3);
            app.SpeciesDropDownLabel = uilabel(app.GridLayout8);
            app.SpeciesDropDownLabel.Text = 'Species';
            app.Species = uidropdown(app.GridLayout8);
            app.Species.Items = {'Rana pipiens', 'Hyla chrysoceles', 'Hyla versicolor', 'Psudecris feriarum'};
            app.Species.Value = 'Rana pipiens';
            app.ParadigmTabGroup = uitabgroup(app.GridLayout);
            app.SearchTab = uitab(app.ParadigmTabGroup);
            app.SearchTab.Title = 'Search';
            app.SearchTabLayout = uigridlayout(app.SearchTab);
            app.SearchCyclerPanel = uipanel(app.SearchTabLayout);
            app.SearchCyclerPanel.Title = 'Cycler';
            app.GridLayout11 = uigridlayout(app.SearchCyclerPanel);
            app.SearchStart = uispinner(app.GridLayout11);
            app.SearchStart.Step = 50;
            app.SearchStart.LowerLimitInclusive = 'off';
            app.SearchStart.Limits = [0 Inf];
            app.SearchStart.Tag = 'frequency';
            app.SearchStart.Enable = 'off';
            app.SearchStart.Value = 300;
            app.SearchStep = uispinner(app.GridLayout11);
            app.SearchStep.Step = 25;
            app.SearchStep.Limits = [0 Inf];
            app.SearchStep.Tag = 'frequency';
            app.SearchStep.Enable = 'off';
            app.SearchStep.Value = 100;
            app.SearchEnd = uispinner(app.GridLayout11);
            app.SearchEnd.Step = 50;
            app.SearchEnd.LowerLimitInclusive = 'off';
            app.SearchEnd.Limits = [0 Inf];
            app.SearchEnd.Tag = 'frequency';
            app.SearchEnd.Enable = 'off';
            app.SearchEnd.Value = 800;
            app.StartLabel = uilabel(app.GridLayout11);
            app.StartLabel.Text = 'Start';
            app.StepLabel = uilabel(app.GridLayout11);
            app.StepLabel.Text = 'Step';
            app.EndLabel = uilabel(app.GridLayout11);
            app.EndLabel.Text = 'End';
            app.SearchSourceParametersTab = uitabgroup(app.SearchTabLayout);
            app.WestTab = uitab(app.SearchSourceParametersTab);
            app.WestTab.Title = 'West';
            app.WestTab.Tag = 'SourceTab';
            app.GridLayout29 = uigridlayout(app.WestTab);
            app.WestSearchCarrierPanel = uipanel(app.GridLayout29);
            app.WestSearchCarrierPanel.Title = 'Carrier';
            app.SearchCarrierLayout = uigridlayout(app.WestSearchCarrierPanel);
            app.GridLayout14_15 = uigridlayout(app.SearchCarrierLayout);
            app.FrequencyHzLabel_5 = uilabel(app.GridLayout14_15);
            app.FrequencyHzLabel_5.Text = 'Frequency(Hz)';
            app.EnergyLabel_5 = uilabel(app.GridLayout14_15);
            app.EnergyLabel_5.Text = 'Energy';
            app.GridLayout14_16 = uigridlayout(app.SearchCarrierLayout);
            app.CheckBox_13 = uicheckbox(app.GridLayout14_16);
            app.CheckBox_13.Tag = 'check';
            app.CheckBox_13.Text = '';
            app.CheckBox_13.Value = true;
            app.Spinner_53 = uispinner(app.GridLayout14_16);
            app.Spinner_53.Tag = 'frequency';
            app.Spinner_53.Value = 150;
            app.Spinner_54 = uispinner(app.GridLayout14_16);
            app.Spinner_54.Step = 0.25;
            app.Spinner_54.Tag = 'energy';
            app.Spinner_54.Value = 1;
            app.GridLayout14_17 = uigridlayout(app.SearchCarrierLayout);
            app.CheckBox_14 = uicheckbox(app.GridLayout14_17);
            app.CheckBox_14.Tag = 'check';
            app.CheckBox_14.Text = '';
            app.CheckBox_14.Value = true;
            app.Spinner_55 = uispinner(app.GridLayout14_17);
            app.Spinner_55.Tag = 'frequency';
            app.Spinner_55.Value = 300;
            app.Spinner_56 = uispinner(app.GridLayout14_17);
            app.Spinner_56.Step = 0.25;
            app.Spinner_56.Tag = 'energy';
            app.Spinner_56.Value = 1;
            app.GridLayout14_18 = uigridlayout(app.SearchCarrierLayout);
            app.CheckBox_15 = uicheckbox(app.GridLayout14_18);
            app.CheckBox_15.Tag = 'check';
            app.CheckBox_15.Text = '';
            app.CheckBox_15.Value = true;
            app.Spinner_57 = uispinner(app.GridLayout14_18);
            app.Spinner_57.Tag = 'frequency';
            app.Spinner_57.Value = 600;
            app.Spinner_58 = uispinner(app.GridLayout14_18);
            app.Spinner_58.Step = 0.25;
            app.Spinner_58.Tag = 'energy';
            app.Spinner_58.Value = 1;
            app.GridLayout14_19 = uigridlayout(app.SearchCarrierLayout);
            app.CheckBox_16 = uicheckbox(app.GridLayout14_19);
            app.CheckBox_16.Tag = 'check';
            app.CheckBox_16.Text = '';
            app.CheckBox_16.Value = true;
            app.Spinner_59 = uispinner(app.GridLayout14_19);
            app.Spinner_59.Tag = 'frequency';
            app.Spinner_59.Value = 1100;
            app.Spinner_60 = uispinner(app.GridLayout14_19);
            app.Spinner_60.Step = 0.25;
            app.Spinner_60.Tag = 'energy';
            app.Spinner_60.Value = 1;
            app.GridLayout14_20 = uigridlayout(app.SearchCarrierLayout);
            app.CheckBox_17 = uicheckbox(app.GridLayout14_20);
            app.CheckBox_17.Tag = 'check';
            app.CheckBox_17.Text = '';
            app.CheckBox_17.Value = true;
            app.Spinner_61 = uispinner(app.GridLayout14_20);
            app.Spinner_61.Tag = 'frequency';
            app.Spinner_61.Value = 1600;
            app.Spinner_62 = uispinner(app.GridLayout14_20);
            app.Spinner_62.Step = 0.25;
            app.Spinner_62.Tag = 'energy';
            app.Spinner_62.Value = 1;
            app.GridLayout14_21 = uigridlayout(app.SearchCarrierLayout);
            app.CheckBox_18 = uicheckbox(app.GridLayout14_21);
            app.CheckBox_18.Tag = 'check';
            app.CheckBox_18.Text = '';
            app.Spinner_63 = uispinner(app.GridLayout14_21);
            app.Spinner_63.Tag = 'frequency';
            app.Spinner_63.Enable = 'off';
            app.Spinner_63.Value = 1600;
            app.Spinner_64 = uispinner(app.GridLayout14_21);
            app.Spinner_64.Tag = 'energy';
            app.Spinner_64.Enable = 'off';
            app.Spinner_64.Value = 1;
            app.WestSearchModulatorPanel = uipanel(app.GridLayout29);
            app.WestSearchModulatorPanel.Title = 'Modulator';
            app.WestSearchModulatorPanel.Tag = 'SearchModulatorPanel';
            app.GridLayout22 = uigridlayout(app.WestSearchModulatorPanel);
            app.GridLayout14_22 = uigridlayout(app.GridLayout22);
            app.FrequencyHzLabel_6 = uilabel(app.GridLayout14_22);
            app.FrequencyHzLabel_6.Text = 'Frequency(Hz)';
            app.EnergyLabel_6 = uilabel(app.GridLayout14_22);
            app.EnergyLabel_6.Text = 'Energy';
            app.GridLayout14_23 = uigridlayout(app.GridLayout22);
            app.CheckBox_19 = uicheckbox(app.GridLayout14_23);
            app.CheckBox_19.Tag = 'check';
            app.CheckBox_19.Text = '';
            app.CheckBox_19.Value = true;
            app.Spinner_65 = uispinner(app.GridLayout14_23);
            app.Spinner_65.Tag = 'frequency';
            app.Spinner_65.Value = 10;
            app.Spinner_66 = uispinner(app.GridLayout14_23);
            app.Spinner_66.Step = 0.25;
            app.Spinner_66.Tag = 'energy';
            app.Spinner_66.Value = 1;
            app.GridLayout14_24 = uigridlayout(app.GridLayout22);
            app.CheckBox_20 = uicheckbox(app.GridLayout14_24);
            app.CheckBox_20.Tag = 'check';
            app.CheckBox_20.Text = '';
            app.CheckBox_20.Value = true;
            app.Spinner_67 = uispinner(app.GridLayout14_24);
            app.Spinner_67.Tag = 'frequency';
            app.Spinner_67.Value = 20;
            app.Spinner_68 = uispinner(app.GridLayout14_24);
            app.Spinner_68.Step = 0.25;
            app.Spinner_68.Tag = 'energy';
            app.Spinner_68.Value = 1;
            app.GridLayout14_25 = uigridlayout(app.GridLayout22);
            app.CheckBox_21 = uicheckbox(app.GridLayout14_25);
            app.CheckBox_21.Tag = 'check';
            app.CheckBox_21.Text = '';
            app.CheckBox_21.Value = true;
            app.Spinner_69 = uispinner(app.GridLayout14_25);
            app.Spinner_69.Tag = 'frequency';
            app.Spinner_69.Value = 40;
            app.Spinner_70 = uispinner(app.GridLayout14_25);
            app.Spinner_70.Step = 0.25;
            app.Spinner_70.Tag = 'energy';
            app.Spinner_70.Value = 1;
            app.GridLayout14_26 = uigridlayout(app.GridLayout22);
            app.CheckBox_22 = uicheckbox(app.GridLayout14_26);
            app.CheckBox_22.Tag = 'check';
            app.CheckBox_22.Text = '';
            app.CheckBox_22.Value = true;
            app.Spinner_71 = uispinner(app.GridLayout14_26);
            app.Spinner_71.Tag = 'frequency';
            app.Spinner_71.Value = 60;
            app.Spinner_72 = uispinner(app.GridLayout14_26);
            app.Spinner_72.Step = 0.25;
            app.Spinner_72.Tag = 'energy';
            app.Spinner_72.Value = 1;
            app.GridLayout14_27 = uigridlayout(app.GridLayout22);
            app.CheckBox_23 = uicheckbox(app.GridLayout14_27);
            app.CheckBox_23.Tag = 'check';
            app.CheckBox_23.Text = '';
            app.CheckBox_23.Value = true;
            app.Spinner_73 = uispinner(app.GridLayout14_27);
            app.Spinner_73.Tag = 'frequency';
            app.Spinner_73.Value = 80;
            app.Spinner_74 = uispinner(app.GridLayout14_27);
            app.Spinner_74.Step = 0.25;
            app.Spinner_74.Tag = 'energy';
            app.Spinner_74.Value = 1;
            app.GridLayout14_28 = uigridlayout(app.GridLayout22);
            app.CheckBox_24 = uicheckbox(app.GridLayout14_28);
            app.CheckBox_24.Tag = 'check';
            app.CheckBox_24.Text = '';
            app.Spinner_75 = uispinner(app.GridLayout14_28);
            app.Spinner_75.Tag = 'frequency';
            app.Spinner_75.Enable = 'off';
            app.Spinner_75.Value = 60;
            app.Spinner_76 = uispinner(app.GridLayout14_28);
            app.Spinner_76.Tag = 'energy';
            app.Spinner_76.Enable = 'off';
            app.Spinner_76.Value = 1;
            app.EastTab = uitab(app.SearchSourceParametersTab);
            app.EastTab.Title = 'East';
            app.EastTab.Tag = 'SourceTab';
            app.GridLayout30 = uigridlayout(app.EastTab);
            app.EastSearchCarrierPanel = uipanel(app.GridLayout30);
            app.EastSearchCarrierPanel.Title = 'Carrier';
            app.SearchCarrierLayout_2 = uigridlayout(app.EastSearchCarrierPanel);
            app.GridLayout14_29 = uigridlayout(app.SearchCarrierLayout_2);
            app.FrequencyHzLabel_7 = uilabel(app.GridLayout14_29);
            app.FrequencyHzLabel_7.Text = 'Frequency(Hz)';
            app.EnergyLabel_7 = uilabel(app.GridLayout14_29);
            app.EnergyLabel_7.Text = 'Energy';
            app.GridLayout14_30 = uigridlayout(app.SearchCarrierLayout_2);
            app.CheckBox_25 = uicheckbox(app.GridLayout14_30);
            app.CheckBox_25.Tag = 'check';
            app.CheckBox_25.Text = '';
            app.CheckBox_25.Value = true;
            app.Spinner_77 = uispinner(app.GridLayout14_30);
            app.Spinner_77.Tag = 'frequency';
            app.Spinner_77.Value = 150;
            app.Spinner_78 = uispinner(app.GridLayout14_30);
            app.Spinner_78.Step = 0.25;
            app.Spinner_78.Tag = 'energy';
            app.Spinner_78.Value = 1;
            app.GridLayout14_31 = uigridlayout(app.SearchCarrierLayout_2);
            app.CheckBox_26 = uicheckbox(app.GridLayout14_31);
            app.CheckBox_26.Tag = 'check';
            app.CheckBox_26.Text = '';
            app.CheckBox_26.Value = true;
            app.Spinner_79 = uispinner(app.GridLayout14_31);
            app.Spinner_79.Tag = 'frequency';
            app.Spinner_79.Value = 300;
            app.Spinner_80 = uispinner(app.GridLayout14_31);
            app.Spinner_80.Step = 0.25;
            app.Spinner_80.Tag = 'energy';
            app.Spinner_80.Value = 1;
            app.GridLayout14_32 = uigridlayout(app.SearchCarrierLayout_2);
            app.CheckBox_27 = uicheckbox(app.GridLayout14_32);
            app.CheckBox_27.Tag = 'check';
            app.CheckBox_27.Text = '';
            app.CheckBox_27.Value = true;
            app.Spinner_81 = uispinner(app.GridLayout14_32);
            app.Spinner_81.Tag = 'frequency';
            app.Spinner_81.Value = 600;
            app.Spinner_82 = uispinner(app.GridLayout14_32);
            app.Spinner_82.Step = 0.25;
            app.Spinner_82.Tag = 'energy';
            app.Spinner_82.Value = 1;
            app.GridLayout14_33 = uigridlayout(app.SearchCarrierLayout_2);
            app.CheckBox_28 = uicheckbox(app.GridLayout14_33);
            app.CheckBox_28.Tag = 'check';
            app.CheckBox_28.Text = '';
            app.CheckBox_28.Value = true;
            app.Spinner_83 = uispinner(app.GridLayout14_33);
            app.Spinner_83.Tag = 'frequency';
            app.Spinner_83.Value = 1100;
            app.Spinner_84 = uispinner(app.GridLayout14_33);
            app.Spinner_84.Step = 0.25;
            app.Spinner_84.Tag = 'energy';
            app.Spinner_84.Value = 1;
            app.GridLayout14_34 = uigridlayout(app.SearchCarrierLayout_2);
            app.CheckBox_29 = uicheckbox(app.GridLayout14_34);
            app.CheckBox_29.Tag = 'check';
            app.CheckBox_29.Text = '';
            app.CheckBox_29.Value = true;
            app.Spinner_85 = uispinner(app.GridLayout14_34);
            app.Spinner_85.Tag = 'frequency';
            app.Spinner_85.Value = 1600;
            app.Spinner_86 = uispinner(app.GridLayout14_34);
            app.Spinner_86.Step = 0.25;
            app.Spinner_86.Tag = 'energy';
            app.Spinner_86.Value = 1;
            app.GridLayout14_35 = uigridlayout(app.SearchCarrierLayout_2);
            app.CheckBox_30 = uicheckbox(app.GridLayout14_35);
            app.CheckBox_30.Tag = 'check';
            app.CheckBox_30.Text = '';
            app.Spinner_87 = uispinner(app.GridLayout14_35);
            app.Spinner_87.Tag = 'frequency';
            app.Spinner_87.Enable = 'off';
            app.Spinner_87.Value = 1600;
            app.Spinner_88 = uispinner(app.GridLayout14_35);
            app.Spinner_88.Tag = 'energy';
            app.Spinner_88.Enable = 'off';
            app.Spinner_88.Value = 1;
            app.SearchModulatorPanel_2 = uipanel(app.GridLayout30);
            app.SearchModulatorPanel_2.Title = 'Modulator';
            app.SearchModulatorPanel_2.Tag = 'SearchModulatorPanel';
            app.GridLayout22_2 = uigridlayout(app.SearchModulatorPanel_2);
            app.GridLayout14_36 = uigridlayout(app.GridLayout22_2);
            app.FrequencyHzLabel_8 = uilabel(app.GridLayout14_36);
            app.FrequencyHzLabel_8.Text = 'Frequency(Hz)';
            app.EnergyLabel_8 = uilabel(app.GridLayout14_36);
            app.EnergyLabel_8.Text = 'Energy';
            app.GridLayout14_37 = uigridlayout(app.GridLayout22_2);
            app.CheckBox_31 = uicheckbox(app.GridLayout14_37);
            app.CheckBox_31.Tag = 'check';
            app.CheckBox_31.Text = '';
            app.CheckBox_31.Value = true;
            app.Spinner_89 = uispinner(app.GridLayout14_37);
            app.Spinner_89.Tag = 'frequency';
            app.Spinner_89.Value = 10;
            app.Spinner_90 = uispinner(app.GridLayout14_37);
            app.Spinner_90.Step = 0.25;
            app.Spinner_90.Tag = 'energy';
            app.Spinner_90.Value = 1;
            app.GridLayout14_38 = uigridlayout(app.GridLayout22_2);
            app.CheckBox_32 = uicheckbox(app.GridLayout14_38);
            app.CheckBox_32.Tag = 'check';
            app.CheckBox_32.Text = '';
            app.CheckBox_32.Value = true;
            app.Spinner_91 = uispinner(app.GridLayout14_38);
            app.Spinner_91.Tag = 'frequency';
            app.Spinner_91.Value = 20;
            app.Spinner_92 = uispinner(app.GridLayout14_38);
            app.Spinner_92.Step = 0.25;
            app.Spinner_92.Tag = 'energy';
            app.Spinner_92.Value = 1;
            app.GridLayout14_39 = uigridlayout(app.GridLayout22_2);
            app.CheckBox_33 = uicheckbox(app.GridLayout14_39);
            app.CheckBox_33.Tag = 'check';
            app.CheckBox_33.Text = '';
            app.CheckBox_33.Value = true;
            app.Spinner_93 = uispinner(app.GridLayout14_39);
            app.Spinner_93.Tag = 'frequency';
            app.Spinner_93.Value = 40;
            app.Spinner_94 = uispinner(app.GridLayout14_39);
            app.Spinner_94.Step = 0.25;
            app.Spinner_94.Tag = 'energy';
            app.Spinner_94.Value = 1;
            app.GridLayout14_40 = uigridlayout(app.GridLayout22_2);
            app.CheckBox_34 = uicheckbox(app.GridLayout14_40);
            app.CheckBox_34.Tag = 'check';
            app.CheckBox_34.Text = '';
            app.CheckBox_34.Value = true;
            app.Spinner_95 = uispinner(app.GridLayout14_40);
            app.Spinner_95.Tag = 'frequency';
            app.Spinner_95.Value = 60;
            app.Spinner_96 = uispinner(app.GridLayout14_40);
            app.Spinner_96.Step = 0.25;
            app.Spinner_96.Tag = 'energy';
            app.Spinner_96.Value = 1;
            app.GridLayout14_41 = uigridlayout(app.GridLayout22_2);
            app.CheckBox_35 = uicheckbox(app.GridLayout14_41);
            app.CheckBox_35.Tag = 'check';
            app.CheckBox_35.Text = '';
            app.CheckBox_35.Value = true;
            app.Spinner_97 = uispinner(app.GridLayout14_41);
            app.Spinner_97.Tag = 'frequency';
            app.Spinner_97.Value = 80;
            app.Spinner_98 = uispinner(app.GridLayout14_41);
            app.Spinner_98.Step = 0.25;
            app.Spinner_98.Tag = 'energy';
            app.Spinner_98.Value = 1;
            app.GridLayout14_42 = uigridlayout(app.GridLayout22_2);
            app.CheckBox_36 = uicheckbox(app.GridLayout14_42);
            app.CheckBox_36.Tag = 'check';
            app.CheckBox_36.Text = '';
            app.Spinner_99 = uispinner(app.GridLayout14_42);
            app.Spinner_99.Tag = 'frequency';
            app.Spinner_99.Enable = 'off';
            app.Spinner_99.Value = 60;
            app.Spinner_100 = uispinner(app.GridLayout14_42);
            app.Spinner_100.Tag = 'energy';
            app.Spinner_100.Enable = 'off';
            app.Spinner_100.Value = 1;
            app.SearchMode = uiknob(app.SearchTabLayout, 'discrete');
            app.SearchMode.Items = {'Fixed Carrier', 'Normal', 'Fixed Modulation'};
            app.SearchMode.ValueChangedFcn = createCallbackFcn(app, @SearchModeValueChanged, true);
            app.SearchMode.Value = 'Normal';
            app.SearchSourceParametersSwitch = uiswitch(app.SearchTabLayout, 'slider');
            app.SearchSourceParametersSwitch.Items = {'Unified', 'Independent'};
            app.SearchSourceParametersSwitch.Value = 'Unified';
            app.Tab2 = uitab(app.ParadigmTabGroup);
            app.Tab2.Title = 'Tab2';
            app.Panel_7 = uipanel(app.GridLayout);
            app.GridLayout12 = uigridlayout(app.Panel_7);
            app.WestCarrierPlot = uiaxes(app.GridLayout12);
            title(app.WestCarrierPlot, 'West Carrier')
            xlabel(app.WestCarrierPlot, 'time (sec)')
            ylabel(app.WestCarrierPlot, 'Amplitude (V)')
            zlabel(app.WestCarrierPlot, 'Z')
            app.WestModulatorPlot = uiaxes(app.GridLayout12);
            title(app.WestModulatorPlot, 'West Modulator')
            xlabel(app.WestModulatorPlot, 'time (sec)')
            ylabel(app.WestModulatorPlot, 'Amplitude (V)')
            zlabel(app.WestModulatorPlot, 'Z')
            app.WestStimulusPlot = uiaxes(app.GridLayout12);
            title(app.WestStimulusPlot, 'West Stimulus')
            xlabel(app.WestStimulusPlot, 'time (sec)')
            ylabel(app.WestStimulusPlot, 'Amplitude (V)')
            zlabel(app.WestStimulusPlot, 'Z')
            app.EastCarrierPlot = uiaxes(app.GridLayout12);
            title(app.EastCarrierPlot, 'East Carrier')
            xlabel(app.EastCarrierPlot, 'X')
            ylabel(app.EastCarrierPlot, 'Y')
            zlabel(app.EastCarrierPlot, 'Z')
            app.EastModulatorPlot = uiaxes(app.GridLayout12);
            title(app.EastModulatorPlot, 'East Modulator')
            xlabel(app.EastModulatorPlot, 'X')
            ylabel(app.EastModulatorPlot, 'Y')
            zlabel(app.EastModulatorPlot, 'Z')
            app.EastStimulusPlot = uiaxes(app.GridLayout12);
            title(app.EastStimulusPlot, 'East Stimulus')
            xlabel(app.EastStimulusPlot, 'X')
            ylabel(app.EastStimulusPlot, 'Y')
            zlabel(app.EastStimulusPlot, 'Z')
            app.EastAttenuationSlider = uislider(app.GridLayout12);
            app.EastAttenuationSlider.Limits = [-72 -6];
            app.EastAttenuationSlider.MajorTicks = [-72 -69 -66 -63 -60 -57 -54 -51 -48 -45 -42 -39 -36 -33 -30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.EastAttenuationSlider.ValueChangedFcn = createCallbackFcn(app, @EastAttenuationSliderValueChanged, true);
            app.EastAttenuationSlider.Value = -27;
            app.WestAttenuationSlider = uislider(app.GridLayout12);
            app.WestAttenuationSlider.Limits = [-72 -6];
            app.WestAttenuationSlider.MajorTicks = [-72 -69 -66 -63 -60 -57 -54 -51 -48 -45 -42 -39 -36 -33 -30 -27 -24 -21 -18 -15 -12 -9 -6 -3 0];
            app.WestAttenuationSlider.ValueChangedFcn = createCallbackFcn(app, @WestAttenuationSliderValueChanged, true);
            app.WestAttenuationSlider.Value = -27;
            app.WestattndBLabel = uilabel(app.GridLayout12);
            app.WestattndBLabel.Text = 'West (attn dB)';
            app.EastattndBLabel = uilabel(app.GridLayout12);
            app.EastattndBLabel.Text = 'East (attn dB)';
            app.WestAttenuation = uispinner(app.GridLayout12);
            app.WestAttenuation.Step = 3;
            app.WestAttenuation.Limits = [-72 -6];
            app.WestAttenuation.ValueChangedFcn = createCallbackFcn(app, @WestAttenuationValueChanged, true);
            app.WestAttenuation.Value = -27;
            app.EastAttenuation = uispinner(app.GridLayout12);
            app.EastAttenuation.Step = 3;
            app.EastAttenuation.Limits = [-72 -6];
            app.EastAttenuation.ValueChangedFcn = createCallbackFcn(app, @EastAttenuationValueChanged, true);
            app.EastAttenuation.Value = -27;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stimulusV2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end